classdef Finances_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Finances                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        ImportExpensesxlsxMenu   matlab.ui.container.Menu
        ClonePreviousMonthMenu   matlab.ui.container.Menu
        TabGroup                 matlab.ui.container.TabGroup
        BalancesTab_2            matlab.ui.container.Tab
        Balance_Axes             matlab.ui.control.UIAxes
        IncomeandExpenseTab      matlab.ui.container.Tab
        IncExp_Axes              matlab.ui.control.UIAxes
        IncPie_Axes              matlab.ui.control.UIAxes
        ExpPie_Axes              matlab.ui.control.UIAxes
        MonthPanel               matlab.ui.container.Panel
        YearDropDownLabel        matlab.ui.control.Label
        YearDropDown             matlab.ui.control.DropDown
        MonthSpinner             matlab.ui.control.Spinner
        NotesTextAreaLabel       matlab.ui.control.Label
        NotesTextArea            matlab.ui.control.TextArea
        SaveMonthButton          matlab.ui.control.Button
        MonthDropDownLabel       matlab.ui.control.Label
        MonthDropDown            matlab.ui.control.DropDown
        TabGroup2                matlab.ui.container.TabGroup
        BalancesTab              matlab.ui.container.Tab
        WellsFargoLabel          matlab.ui.control.Label
        WellsFargoBox            matlab.ui.control.NumericEditField
        RobinhoodEditFieldLabel  matlab.ui.control.Label
        RobinhoodBox             matlab.ui.control.NumericEditField
        PayPalEditFieldLabel     matlab.ui.control.Label
        PayPalBox                matlab.ui.control.NumericEditField
        NSLSCEditFieldLabel      matlab.ui.control.Label
        NSLSCBox                 matlab.ui.control.NumericEditField
        AssetsLabel              matlab.ui.control.Label
        WellsFargoBox_2          matlab.ui.control.NumericEditField
        FinnCULabel              matlab.ui.control.Label
        PayPalBox_2              matlab.ui.control.NumericEditField
        IncomeandExpenseTab_2    matlab.ui.container.Tab
        IncomeJobLabel           matlab.ui.control.Label
        WellsFargoBox_3          matlab.ui.control.NumericEditField
        IncomeOtherLabel         matlab.ui.control.Label
        WellsFargoBox_4          matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Year % Description
        Month % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MonthDropDown.ItemsData = 1:1:12;
            app.MonthDropDown.Value = 1;
            Data = [];
            Data.Y2020 = [];
            Data.Y2020.Balances = [];
            Data.Y2020.Balance_Deltas = [];            
            Data.Y2020.Expenses = [];  
            Data.Y2020.Notes = [];             
            Data.Y2020.Complete = [];     
            
            app.Year = 2020;
            app.Month = 1;
            
        end

        % Value changed function: MonthSpinner
        function MonthSpinnerValueChanged(app, event)
            app.MonthDropDown.Value = -app.MonthSpinner.Value;  
            app.Month = -app.MonthSpinner.Value;            
        end

        % Value changed function: MonthDropDown
        function MonthDropDownValueChanged(app, event)
            app.MonthSpinner.Value = -app.MonthDropDown.Value; 
            app.Month = app.MonthDropDown.Value;             
        end

        % Button pushed function: SaveMonthButton
        function SaveMonthButtonPushed(app, event)
            YearName = strcat('Y',num2str(app.Year));
            
            Data(app.Month).(char(YearName)).Balances.WF = app.WellsFargoBox.Value - app.WellsFargoBox_2.Value;
            Data(app.Month).(char(YearName)).Balances.WF_Savings = app.WellsFargoBox_2.Value;              
            Data(app.Month).(char(YearName)).Balances.RH = app.RobinhoodBox.Value;            
            Data(app.Month).(char(YearName)).Balances.FinnCU = app.FinnCUBox.Value;              
            Data(app.Month).(char(YearName)).Balances.PayPal = app.PayPalBox.Value;                        
            Data(app.Month).(char(YearName)).Balances.NSLSC = app.NSLSCBox.Value;             
            
            if app.Month == 1 %Find year and month of previous month for delta calcs
            PrevYear = strcat('Y',num2str(app.Year-1)); 
            PrevMonth = app.Month;
            else
                PrevYear = app.Year;
                PrevMonth = app.Month - 1;
            end
            
            if app.Year == 2020 && app.Month < 6 %Start of entries, cant find deltas until june (2nd month)
            Data(app.Month).(char(YearName)).Balance_Deltas.WF = 0;
            Data(app.Month).(char(YearName)).Balance_Deltas.WF_Savings = 0;              
            Data(app.Month).(char(YearName)).Balance_Deltas.RH = 0;            
            Data(app.Month).(char(YearName)).Balance_Deltas.FinnCU = 0;              
            Data(app.Month).(char(YearName)).Balance_Deltas.PayPal = 0;                        
            Data(app.Month).(char(YearName)).Balance_Deltas.NSLSC = 0;                
            else
            Data(app.Month).(char(YearName)).Balance_Deltas.WF = Data(app.Month).(char(YearName)).Balances.WF - Data(PrevMonth).(char(PrevYear)).Balances.WF;
            Data(app.Month).(char(YearName)).Balance_Deltas.WF_Savings = Data(app.Month).(char(YearName)).Balances.WF_Savings - Data(PrevMonth).(char(PrevYear)).Balances.WF_Savings;              
            Data(app.Month).(char(YearName)).Balance_Deltas.RH = Data(app.Month).(char(YearName)).Balances.RH - Data(PrevMonth).(char(PrevYear)).Balances.RH;            
            Data(app.Month).(char(YearName)).Balance_Deltas.FinnCU = Data(app.Month).(char(YearName)).Balances.FinnCU - Data(PrevMonth).(char(PrevYear)).Balances.FinnCU;              
            Data(app.Month).(char(YearName)).Balance_Deltas.PayPal = Data(app.Month).(char(YearName)).Balances.PayPal - Data(PrevMonth).(char(PrevYear)).Balances.PayPal;                      
            Data(app.Month).(char(YearName)).Balance_Deltas.NSLSC = Data(app.Month).(char(YearName)).Balances.NSLSC - Data(PrevMonth).(char(PrevYear)).Balances.NSLSC;              
            end
            
            Data(app.Month).(char(YearName)).Notes = app.NotesTextArea.Value;                                 
            
        end

        % Value changed function: YearDropDown
        function YearDropDownValueChanged(app, event)
            app.Year = cell2mat(app.YearDropDown.Value);           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Finances = uifigure('Visible', 'off');
            app.Finances.Name = 'Orion Finances';
            app.FileMenu = uimenu(app.Finances);
            app.FileMenu.Text = ' File ';
            app.ImportExpensesxlsxMenu = uimenu(app.FileMenu);
            app.ImportExpensesxlsxMenu.Text = 'Import Expenses (.xlsx)';
            app.ClonePreviousMonthMenu = uimenu(app.FileMenu);
            app.ClonePreviousMonthMenu.Separator = 'on';
            app.ClonePreviousMonthMenu.Text = 'Clone Previous Month';
            app.TabGroup = uitabgroup(app.Finances);
            app.BalancesTab_2 = uitab(app.TabGroup);
            app.BalancesTab_2.Title = 'Balances';
            app.Balance_Axes = uiaxes(app.BalancesTab_2);
            title(app.Balance_Axes, 'Title')
            xlabel(app.Balance_Axes, 'X')
            ylabel(app.Balance_Axes, 'Y')
            app.IncomeandExpenseTab = uitab(app.TabGroup);
            app.IncomeandExpenseTab.Title = 'Income and Expense';
            app.IncExp_Axes = uiaxes(app.IncomeandExpenseTab);
            title(app.IncExp_Axes, 'Title')
            xlabel(app.IncExp_Axes, 'X')
            ylabel(app.IncExp_Axes, 'Y')
            app.IncPie_Axes = uiaxes(app.IncomeandExpenseTab);
            title(app.IncPie_Axes, 'Title')
            xlabel(app.IncPie_Axes, 'X')
            ylabel(app.IncPie_Axes, 'Y')
            app.ExpPie_Axes = uiaxes(app.IncomeandExpenseTab);
            title(app.ExpPie_Axes, 'Title')
            xlabel(app.ExpPie_Axes, 'X')
            ylabel(app.ExpPie_Axes, 'Y')
            app.MonthPanel = uipanel(app.Finances);
            app.MonthPanel.Title = 'Month';
            app.YearDropDownLabel = uilabel(app.MonthPanel);
            app.YearDropDownLabel.Text = 'Year';
            app.YearDropDown = uidropdown(app.MonthPanel);
            app.YearDropDown.Items = {'2020', '2021'};
            app.YearDropDown.Editable = 'on';
            app.YearDropDown.ValueChangedFcn = createCallbackFcn(app, @YearDropDownValueChanged, true);
            app.YearDropDown.Value = '2020';
            app.MonthSpinner = uispinner(app.MonthPanel);
            app.MonthSpinner.Limits = [1 12];
            app.MonthSpinner.RoundFractionalValues = 'on';
            app.MonthSpinner.ValueChangedFcn = createCallbackFcn(app, @MonthSpinnerValueChanged, true);
            app.MonthSpinner.Value = 1;
            app.NotesTextAreaLabel = uilabel(app.MonthPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.MonthPanel);
            app.SaveMonthButton = uibutton(app.MonthPanel, 'push');
            app.SaveMonthButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMonthButtonPushed, true);
            app.SaveMonthButton.Text = 'Save Month';
            app.MonthDropDownLabel = uilabel(app.MonthPanel);
            app.MonthDropDownLabel.Text = 'Month';
            app.MonthDropDown = uidropdown(app.MonthPanel);
            app.MonthDropDown.Items = {'JAN', 'FEB', 'MAR', 'APR', 'MAY', 'JUN', 'JUL', 'AUG', 'SEP', 'OCT', 'NOV', 'DEC'};
            app.MonthDropDown.Editable = 'on';
            app.MonthDropDown.ValueChangedFcn = createCallbackFcn(app, @MonthDropDownValueChanged, true);
            app.MonthDropDown.Value = 'JAN';
            app.TabGroup2 = uitabgroup(app.Finances);
            app.BalancesTab = uitab(app.TabGroup2);
            app.BalancesTab.Title = 'Balances';
            app.WellsFargoLabel = uilabel(app.BalancesTab);
            app.WellsFargoLabel.Text = 'Wells Fargo';
            app.WellsFargoBox = uieditfield(app.BalancesTab, 'numeric');
            app.RobinhoodEditFieldLabel = uilabel(app.BalancesTab);
            app.RobinhoodEditFieldLabel.Text = 'Robinhood';
            app.RobinhoodBox = uieditfield(app.BalancesTab, 'numeric');
            app.PayPalEditFieldLabel = uilabel(app.BalancesTab);
            app.PayPalEditFieldLabel.Text = 'PayPal';
            app.PayPalBox = uieditfield(app.BalancesTab, 'numeric');
            app.NSLSCEditFieldLabel = uilabel(app.BalancesTab);
            app.NSLSCEditFieldLabel.Text = 'NSLSC';
            app.NSLSCBox = uieditfield(app.BalancesTab, 'numeric');
            app.AssetsLabel = uilabel(app.BalancesTab);
            app.AssetsLabel.Text = 'Assets';
            app.WellsFargoBox_2 = uieditfield(app.BalancesTab, 'numeric');
            app.FinnCULabel = uilabel(app.BalancesTab);
            app.FinnCULabel.Text = 'FinnCU';
            app.PayPalBox_2 = uieditfield(app.BalancesTab, 'numeric');
            app.IncomeandExpenseTab_2 = uitab(app.TabGroup2);
            app.IncomeandExpenseTab_2.Title = 'Income and Expense';
            app.IncomeJobLabel = uilabel(app.IncomeandExpenseTab_2);
            app.IncomeJobLabel.Text = 'Income - Job';
            app.WellsFargoBox_3 = uieditfield(app.IncomeandExpenseTab_2, 'numeric');
            app.IncomeOtherLabel = uilabel(app.IncomeandExpenseTab_2);
            app.IncomeOtherLabel.Text = 'Income - Other';
            app.WellsFargoBox_4 = uieditfield(app.IncomeandExpenseTab_2, 'numeric');
            % Show the figure after all components are created
            app.Finances.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Finances_Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Finances)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Finances)
        end
    end
end